classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SelectFileButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            
            [file,path] = uigetfile('*.png');
            
            [L, G, globalX, globalK, LOrig, GOrig, optimalKSet] = computeGlobal();
            
            in = imread([path,  file]);
            
            I = double(in(:)); % vectorize
            
            names = getTrainingSetNames();
            
            imageMeans = [];
            
            for i = 1 : size(names, 2)
                
                dr = char('TrainingImages\' + names(i) + '\UnProcessed');
                
                images = loadDirectory('a', dr);
                
                [Xhat, X] = computeUnbiasedMatrix(images);
                X = double(X);
                
                T = X' * L; % map this image into our L space
                
                imageMeans = [imageMeans ; mean(T)]; % get middle
            end
            
            % now that we have the middle points of all our training images mapped
            % we can map the test image, and get the min distance 
            
            Test = I' * L;
            
            
            best = intmax;
            bestIndex = 0;
            
            % classify test image
            for i = 1 : size(imageMeans, 1)
                m = imageMeans(i, :)';
                diff = abs(norm(Test) - norm(m));
                if ( diff < best )
                    best = diff;
                    bestIndex = i;
                end
            end
            
            % we now know that our image lies in the ith image space
            
            % now we estimate specific image and pose
            dr = char('TrainingImages\' + names(bestIndex) + '\UnProcessed');
            images = loadDirectory('a', dr);
            [Xhat, X] = computeUnbiasedMatrix(images);
            X = double(X);
            
            best = intmax;
            bestIndex = 0;
            for i = 1 : size(X, 2)
                Train = X(:, i)';
                diff = abs(norm(I) - norm(Train));
                if ( diff < best )
                    best = diff;
                    bestIndex = i;
                end
            end


            % display back to user
            figure;
            montage([in, reshape( X(:, i), 128,[] )]);
            title('Actual / Prediction');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end